classdef imageClassify_App_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        Part                       matlab.ui.control.UIAxes
        Full                       matlab.ui.control.UIAxes
        uipanel1                   matlab.ui.container.Panel
        Save                       matlab.ui.control.Button
        Start                      matlab.ui.control.Button
        Reset                      matlab.ui.control.Button
        LoadMode                   matlab.ui.control.Button
        Counter                    matlab.ui.control.Label
        text_Default_Label         matlab.ui.control.Label
        text_active_Label          matlab.ui.control.Label
        Default_Group              matlab.ui.container.ButtonGroup
        EmptyButton_2              matlab.ui.control.RadioButton
        UnderButton                matlab.ui.control.RadioButton
        OKButton                   matlab.ui.control.RadioButton
        OverButton                 matlab.ui.control.RadioButton
        ClassTypesGroup            matlab.ui.container.ButtonGroup
        EmptyButton                matlab.ui.control.RadioButton
        UnderButton_4              matlab.ui.control.RadioButton
        OKButton_2                 matlab.ui.control.RadioButton
        OverButton_2               matlab.ui.control.RadioButton
        ClearAll                   matlab.ui.control.Button
        Save_to_file               matlab.ui.control.Button
        ModeSwitch                 matlab.ui.control.Switch
        ShiftEditField             matlab.ui.control.EditField
        Shift                      matlab.ui.control.Button
        SetNumField                matlab.ui.control.EditField
        SetNum                     matlab.ui.control.Button
        GeneratorButton            matlab.ui.control.Button
        ImagegenerationLabel       matlab.ui.control.Label
        Modeswitch_img_generation  matlab.ui.control.Switch
    end

    properties (Access = private)
        N
        LABELS
        coor
        coor2
        x
        y
        xx
        yy
        X
        Y
        XX
        YY
        lable_type
        p
        default
        patches
        Num
        Output
        imglist
        imgnames
        right_click
        middle_click
        saved
        save_flag
        filefolder_1
        filefolder_2
        count
        mode
        labelcount
        labeledNum
        changedetected
        bias
        SetNumVal
        flag_img_gen_mode
        ph
        th
    end
    
    methods (Access = private)
        
        function imgPlot(app)
            function mouseClick(~,b)
                if ~strcmp(app.ModeSwitch.Value,'Labeling')
                    app.changedetected = 1;
                end
                app.Save_to_file.Enable = 0;
                app.Shift.Enable = 0;
                app.SetNum.Enable = 0;
                app.Save.Enable = 1;
                
                temp_x = b.IntersectionPoint(1);
                temp_y = b.IntersectionPoint(2);
                idxt = knnsearch(app.coor,[temp_x temp_y],'k',4) ;
                idx_mat = knnsearch(app.coor2,[temp_x temp_y],'k',1) ;
                % form bounding box
                x0 = min(app.coor(idxt,1)) ;x1 = max(app.coor(idxt,1)) ;
                y0 = min(app.coor(idxt,2)) ;y1 = max(app.coor(idxt,2)) ;
                
                if b.Button == 1
                    app.LABELS(idx_mat) = app.lable_type;
                elseif b.Button == 2
                    app.LABELS(idx_mat) = app.middle_click;
                elseif b.Button == 3
                    app.LABELS(idx_mat) = app.right_click;
                else
                    return
                end
                
                % square points
                coori = [x0 y0 ; x0 y1 ; x1 y1 ; x1 y0 ; x0 y0] ;
                
                if app.LABELS(idx_mat)  == 1
                    color = [0 0 0]+1;
                elseif app.LABELS(idx_mat)  == 2
                    color = [0 0 1];
                elseif app.LABELS(idx_mat)  == 3
                    color = [0 1 0];
                elseif app.LABELS(idx_mat)  == 4
                    color = [1 0 0];
                else
                    color = [0 0 0];
                end
                
                if ~isempty(app.patches(idx_mat).a)
                    delete(app.patches(idx_mat).a)
                end
                app.patches(idx_mat).a = patch(app.Part,coori(:,1),coori(:,2),color,'FaceAlpha',.75) ;
            end
            
            if strcmp(app.ModeSwitch.Value,'Labeling')
                app.Counter.Text = string(app.Num)+"/"+string(app.count);
                try
                    img = imread(app.imglist{app.Num});
                catch
                    app.Num = app.Num +1;
                    imgPlot(app)
                    return
                end
            else
                app.Counter.Text = string(app.labeledNum)+"/"+string(size(app.saved,2));
                img = app.saved(app.labeledNum).img;
            end
            
            cla(app.Part)
            cla(app.Full)
            image(app.Part,[0 1],[0 1],img)
            
            app.p = image(app.Full,[0 1],[0 1],img);
            hold on
            plot(app.Full,app.X,app.Y,'k') ;
            plot(app.Full,app.X',app.Y','k') ;
            app.p.ButtonDownFcn=@mouseClick;
            if ~strcmp(app.ModeSwitch.Value,'Labeling')
                app.LABELS = app.saved(app.labeledNum).labels;
                for iter = 1:app.N^2
                    temp_label =  app.LABELS(iter);
                    idx = knnsearch(app.coor,app.coor2(iter,:),'k',4) ;
                    x0t = min(app.coor(idx,1)) ;x1t = max(app.coor(idx,1)) ;
                    y0t = min(app.coor(idx,2)) ;y1t = max(app.coor(idx,2)) ;
                    coori = [x0t y0t ; x0t y1t ; x1t y1t ; x1t y0t ; x0t y0t] ;
                    if temp_label == 1
                        colort = [0 0 0]+1;
                    elseif temp_label  == 2
                        colort = [0 0 1];
                    elseif temp_label  == 3
                        colort = [0 1 0];
                    elseif temp_label  == 4
                        colort = [1 0 0];
                    else
                        colort = [0 0 0];
                    end
                    
                    if ~isempty(app.patches(iter).a)
                        delete(app.patches(iter).a)
                    end
                    app.patches(iter).a = patch(app.Part,coori(:,1),coori(:,2),colort,'FaceAlpha',.75) ;
                    
                end
            end


        end
        
        function imgSave(app, img )
            
            app.Output(length(app.Output)+1).img = img;
            app.LABELS(app.LABELS == 0) = app.default;
            app.Output(length(app.Output)).labels = app.LABELS;
            app.Output(length(app.Output)).name = app.imgnames(app.Num);
            
            %             imwrite(img_c,filename);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imageClassify_OpeningFcn(app, varargin)
            
            clc
           
            app.N = 10;
            app.LABELS = zeros(app.N);
            app.patches = struct([]);
            app.patches(app.N^2+1).a = [];
            app.x = linspace(0,1,app.N+1) ;
            app.y = linspace(0,1,app.N+1) ;
            app.xx = linspace(0,1,2*app.N+1) ;
            app.yy = linspace(0,1,2*app.N+1) ;
            
            app.xx =app.xx(2:2:end);
            app.yy = app.yy(2:2:end);
            [app.X,app.Y] = meshgrid(app.x,app.y) ;
            [app.XX,app.YY] = meshgrid(app.xx,app.yy);
            app.coor = [app.X(:) app.Y(:)] ;
            app.coor2 = [app.XX(:)  app.YY(:)];
            app.lable_type = strcmp(app.ClassTypesGroup.SelectedObject.Text, ["Empty", "Under" ,"OK","Over"])*[1;2;3;4];
            app.default = strcmp(app.Default_Group.SelectedObject.Text, ["Empty", "Under" ,"OK","Over"])*[1;2;3;4];
            app.flag_img_gen_mode = strcmp(app.Modeswitch_img_generation.Value, ["ON","OFF"])*[1;2];
            app.text_active_Label.Text = "   WELCOME   " ;
            app.text_Default_Label.Text = "Default Label:   " + app.Default_Group.SelectedObject.Text;
            
            app.right_click = 4;
            app.middle_click = 3;
            app.save_flag = 0;
            app.Num = 0;
            app.labeledNum = 0;
            app.changedetected = 0;
            app.bias = 0;
            app.SetNumVal = 0;
            if strcmp(app.ModeSwitch.Value,'Labeling')
                app.mode = 1;
            else
                app.mode = 0;
            end
            
            if exist('Labels_final.mat','file')==2
                app.saved = load('Labels_final');
                app.saved = app.saved.temp;
            else
                app.saved = struct([]);
            end


            if ~exist('labeled\', 'dir')
                mkdir('labeled\')
            end
            
            
            %% jiaqi code
            imgDataPath = 'Paper_data'; %'D:\Local_research\11_6\raw'
            imgDataDir  = dir(imgDataPath);             % ??????
            
            app.imglist = {};
            for i = 1:length(imgDataDir)
                if(isequal(imgDataDir(i).name,'.')||... % ?????????????
                        isequal(imgDataDir(i).name,'..')||...
                        ~imgDataDir(i).isdir)                % ???????????
                    continue;
                end
                subimgDataDir = dir([imgDataPath '\' imgDataDir(i).name]);
                for n = 1:length(subimgDataDir)
                    if(isequal(subimgDataDir(n).name,'.')||... % ?????????????
                            isequal(subimgDataDir(n).name,'..')||...
                            ~subimgDataDir(n).isdir)                % ???????????
                        continue;
                    end
                    imgPath = [imgDataPath '\' imgDataDir(i).name '\' subimgDataDir(n).name '\*.png'];
                    imgDir = dir(imgPath);
                    app.count = 0;
                    for j =1:length(imgDir)                 % ??????
                        if size(app.saved,1)>0
                            if(sum(strcmp([app.saved(:).name],imgDir(j).name))==0)
                                app.count = app.count+1;
                                app.imglist{app.count} = [imgDataPath '\' imgDataDir(i).name '\' subimgDataDir(n).name '\' imgDir(j).name];
                                app.imgnames{app.count} = imgDir(j).name;
                            end
                        else
                            app.count = app.count+1;
                            app.imglist{app.count} = [imgDataPath '\' imgDataDir(i).name '\' subimgDataDir(n).name '\' imgDir(j).name];
                            app.imgnames{app.count} = imgDir(j).name;
                        end
                    end
                end
            end
            
            
            % create folder
            file = 'Test_data_labeled'; % 'D:\Local_research\11_6\results'
            app.filefolder_1 = {'\labeled'};
            app.filefolder_2 = {'\Empty', '\Under' ,'\OK','\Over'};
            for i = 1: length(app.filefolder_1)
                if ~exist([file, app.filefolder_1{i}],'file')
                    mkdir([file, app.filefolder_1{i}])
                    
                end
            end
            app.filefolder_1 = char(strcat(file,app.filefolder_1));
            for ii = 1: length(app.filefolder_2)
                if ~exist([ app.filefolder_1, app.filefolder_2{ii}],'file')
                    mkdir([app.filefolder_1, app.filefolder_2{ii}])
                end
            end
            
        end

        % Button pushed function: Save
        function Save_Callback(app, event)
            app.Save.Text = "Saving ...";
            if app.mode == 1
                filename = app.imglist{app.Num};
                app.Save.Enable = 0;
                %             app.Save_to_file.Enable = 1;
                drawnow
                img = imread(app.imglist{app.Num});
                imgSave(app, img);
                Save_to_fileButtonPushed(app, event)
                movefile (filename ,'labeled')
                %                 app.Num = app.Num+1;
                %                 imgPlot(app)
            else
                app.Save.Enable = 0;
                drawnow
                if (app.changedetected == 1)
                    Save_to_fileButtonPushed(app, event)
                    %                     app.labeledNum = app.labeledNum+1;
                    app.LABELS = app.LABELS*0;
                    %                     imgPlot(app)
                    app.changedetected = 0;
                end
            end
            
            app.Save.Text = "Save & Next";
            app.Shift.Enable = 1;
            app.SetNum.Enable = 1;
            Start_Callback(app, event)
        end

        % Button pushed function: Save_to_file
        function Save_to_fileButtonPushed(app, event)
            if  strcmp(app.ModeSwitch.Value,'Labeling')
%                 temp =  [app.saved,app.Output];
%                 save('Labels', 'temp')
app.filefolder_1 = 'Test_data_labeled';
                for i = 1:size(app.Output,2)
                    img = app.Output(i).img;
                    labels = app.Output(i).labels;
                    name = erase(app.Output(i).name,".png");
                    L = floor(size(img,1)/app.N);
                    for step_x = 1:app.N
                        for step_y = 1:app.N
                            start_x = 1+L*(step_x-1);
                            end_x = L*step_x;
                            start_y = 1+L*(step_y-1);
                            end_y = L*(step_y);
                            slice = img(start_y:end_y,start_x:end_x,:);
                            
                            filename = strcat(app.filefolder_1,app.filefolder_2(labels(step_y,step_x)),...
                                "\", name,"_slice_",num2str(step_y-1),"_",num2str(step_x-1),".png");
                            imwrite(slice,filename);
                        end
                    end
                end
            else
                app.saved(app.labeledNum).labels = app.LABELS;
                temp=app.saved;
                save('Labels_final', 'temp')
                
                img = app.saved(app.labeledNum).img;
                labels = app.saved(app.labeledNum).labels;
                name = erase(app.saved(app.labeledNum).name,".png");
                L = floor(size(img,1)/app.N);
                if (app.flag_img_gen_mode == 1)
                for step_x = 1:app.N
                    for step_y = 1:app.N
                        start_x = 1+L*(step_x-1);
                        end_x = L*step_x;
                        start_y = 1+L*(step_y-1);
                        end_y = L*(step_y);
                        slice = img(start_y:end_y,start_x:end_x,:);
                        
                        filename = strcat(app.filefolder_1,app.filefolder_2(labels(step_y,step_x)),...
                                    "\", name,"_slice_",num2str(step_y),"_",num2str(step_x),".png");
                        if ~(exist(filename,'file')==2)
                            
                            for iter2 = 1:size(app.filefolder_2,2)
                                
                                filename = strcat(app.filefolder_1,app.filefolder_2(iter2),...
                                    "\", name,"_slice_",num2str(step_y),"_",num2str(step_x),".png");
                                if (exist(filename,'file')==2)
                                    delete(filename)
                                end
                            end
                            filename = strcat(app.filefolder_1,app.filefolder_2(labels(step_y,step_x)),...
                            "\", name,"_slice_",num2str(step_y),"_",num2str(step_x),".png");

                        imwrite(slice,filename);
                        end
                        
                    end
                end
                end
                
            end
           
        end

        % Button pushed function: Reset
        function Reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            app.LABELS = app.LABELS*0;
            imgPlot(app)
        end

        % Button pushed function: Start
        function Start_Callback(app, event)
            if ((app.Num >= app.count)&& (app.mode == 1))||((app.mode == 0) && (app.labeledNum >= size(app.saved,2)))
                msgbox("End of this set","modal")
                return
            end
            app.Save_to_file.Enable = 0;
            if app.mode == 1
                
                app.Save.Enable = 0;
                app.Num = app.Num+1;
                imgPlot(app)
                app.LABELS = zeros(app.N);
            else
                
                app.Save.Enable = 0;
                app.labeledNum = app.labeledNum+1;
                imgPlot(app)
            end
        end

        % Callback function
        function pushbutton7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            str = get(handles.edit1,'String');
            if app.Output(str2double(str)) == 1
                class = 'No extrusion';
            elseif app.Output(str2double(str)) == 2
                class = 'Under extrusion';
            elseif app.Output(str2double(str)) == 3
                class = 'OK';
            elseif app.Output(str2double(str)) == 4
                class = 'Over extrusion';
            else
                class = 'Not classified';
            end
            
            imgPlot(app)
            app.Num = str2double(str);
        end

        % Selection changed function: ClassTypesGroup
        function ClassTypesGroupSelectionChanged(app, event)
            selectedButton = app.ClassTypesGroup.SelectedObject;
            temp = strcmp(selectedButton.Text, ["Empty", "Under" ,"OK","Over"]);
            app.lable_type = temp*[1;2;3;4];
            app.text_active_Label.Text = "Select:   " + selectedButton.Text;
            
            if app.lable_type  == 1
                color = [0 0 0]+1;
            elseif app.lable_type  == 2
                color = [0 0 1];
            elseif app.lable_type  == 3
                color = [0 1 0];
            elseif app.lable_type  == 4
                color = [1 0 0];
            else
                color = [0 0 0];
            end
            
            app.Full.BackgroundColor = color;
            app.text_active_Label.BackgroundColor = color;
        end

        % Selection changed function: Default_Group
        function Default_GroupSelectionChanged(app, event)
            selectedButton = app.Default_Group.SelectedObject;
            temp = strcmp(selectedButton.Text, ["Empty", "Under" ,"OK","Over"]);
            app.default = temp*[1;2;3;4];
            app.text_Default_Label.Text = "Default Label:   " + selectedButton.Text;
            
            if app.default  == 1
                color = [0 0 0]+1;
            elseif app.default  == 2
                color = [0 0 1];
            elseif app.default  == 3
                color = [0 1 0];
            elseif app.default  == 4
                color = [1 0 0];
            else
                color = [0 0 0];
            end
            
            app.Part.BackgroundColor = color;
            app.text_Default_Label.BackgroundColor = color;
            
        end

        % Button pushed function: ClearAll
        function ClearAllButtonPushed(app, event)
            delete Labels.mat
            if exist('Test_data_labeled\labeled\', 'dir')
                rmdir('Test_data_labeled\labeled\','s')
            end
            
        end

        % Button pushed function: LoadMode
        function LoadModeButtonPushed(app, event)
            app.LoadMode.Enable = ~app.LoadMode.Enable ;
            if strcmp(app.ModeSwitch.Value,'Labeling')
                app.mode = 1;
            else
                app.mode = 0;
            end
            cla(app.Part)
            cla(app.Full)
            app.LABELS = app.LABELS*0;
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            app.LoadMode.Enable = ~app.LoadMode.Enable ;
        end

        % Button pushed function: Shift
        function ShiftButtonPushed(app, event)
            if ~isnan(app.ShiftEditField.Value)
                if strcmp(app.ShiftEditField.Value,"clear all")
                    app.ClearAll.Enable = ~app.ClearAll.Enable;
                end
                if strcmp(app.ShiftEditField.Value,"generate")
                    app.GeneratorButton.Enable = ~app.GeneratorButton.Enable;
                    app.GeneratorButton.Visible = ~app.GeneratorButton.Visible;
                end
            app.Num = app.Num - app.bias;
            app.labeledNum = app.labeledNum - app.bias;
            app.bias = str2double(app.ShiftEditField.Value);
            app.labeledNum = app.labeledNum + app.bias;
            app.Num = app.Num + app.bias;
            
            
            app.ShiftEditField.Value = '0';
            end
        end

        % Button pushed function: SetNum
        function SetNumButtonPushed(app, event)
            if ~isnan(app.SetNumField.Value)
                if (str2double(app.SetNumField.Value) < 1)
                    msgbox("not valid slide number. Input >1")
                    return
                end
                app.SetNumVal = str2double(app.SetNumField.Value)-1;
                app.labeledNum = app.SetNumVal;
                app.Num = app.SetNumVal;
                app.SetNumField.Value = '0';
            end
        end

        % Value changed function: Modeswitch_img_generation
        function Modeswitch_img_generationValueChanged(app, event)
            app.flag_img_gen_mode = strcmp(app.Modeswitch_img_generation.Value, ["ON","OFF"])*[1;2];
        end

        % Button pushed function: GeneratorButton
        function GeneratorButtonPushed(app, event)
%          Set up the progress bar axis

            cla(app.Part)
            cla(app.Full)
            fh = app.Full;
            fh.XTick = [];
            fh.YTick = [];
            fh.Color = [0.9375 0.9375 0.9375];
            fh.XLim = [0,1];
            fh.YLim = [0,1];
            app.text_active_Label.Text = "Progress";
            % Create empty patch that will be updated
            app.ph = patch(app.Full,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            % Create the percent-complete text that will be updated
            app.th = text(app.Full,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            
            max_iter = size(app.saved,2);
            for iter = 1:max_iter
                app.ph.XData = [0 iter/max_iter  iter/max_iter  0];
                 app.text_active_Label.Text = sprintf('%.0f%%',round(iter/max_iter*100));
                drawnow %update graphics
                img = app.saved(iter).img;
                labels = app.saved(iter).labels;
                name = erase(app.saved(iter).name,".png");
                L = floor(size(img,1)/app.N);
                for step_x = 1:app.N
                    for step_y = 1:app.N
                        start_x = 1+L*(step_x-1);
                        end_x = L*step_x;
                        start_y = 1+L*(step_y-1);
                        end_y = L*(step_y);
                        slice = img(start_y:end_y,start_x:end_x,:);
                        
                        
                        for iter2 = 1:size(app.filefolder_2,2)
                            filename = strcat(app.filefolder_1,app.filefolder_2(iter2),...
                                "\", name,"_slice_",num2str(step_y),"_",num2str(step_x),".png");
                            if (exist(filename,'file')==2)
                                delete(filename)
                            end
                        end
                        filename = strcat(app.filefolder_1,app.filefolder_2(labels(step_y,step_x)),...
                            "\", name,"_slice_",num2str(step_y),"_",num2str(step_x),".png");
                        imwrite(slice,filename);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'imageClassify';
            app.figure1.Tag = 'figure1';
            app.Part = uiaxes(app.figure1);
            app.Part.Visible = 'off';
            app.Part.Tag = 'Part';
            app.Full = uiaxes(app.figure1);
            app.Full.Visible = 'off';
            app.Full.Tag = 'Full';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Panel';
            app.uipanel1.Tag = 'uipanel1';
            app.Save = uibutton(app.uipanel1, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @Save_Callback, true);
            app.Save.Tag = 'Noprint';
            app.Save.Enable = 'off';
            app.Save.Text = 'Save & Next';
            app.Start = uibutton(app.uipanel1, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @Start_Callback, true);
            app.Start.Tag = 'Start';
            app.Start.Text = 'Start / Skip';
            app.Reset = uibutton(app.uipanel1, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @Reset_Callback, true);
            app.Reset.Tag = 'Reset';
            app.Reset.Text = 'Reset';
            app.LoadMode = uibutton(app.uipanel1, 'push');
            app.LoadMode.ButtonPushedFcn = createCallbackFcn(app, @LoadModeButtonPushed, true);
            app.LoadMode.Tag = 'Reset';
            app.LoadMode.Enable = 'off';
            app.LoadMode.Text = 'Load mode';
            app.Counter = uilabel(app.uipanel1);
            app.text_Default_Label = uilabel(app.figure1);
            app.text_Default_Label.Tag = 'text8';
            app.text_Default_Label.Text = {'Default Lable'; ''};
            app.text_active_Label = uilabel(app.figure1);
            app.text_active_Label.Tag = 'text9';
            app.text_active_Label.Text = {'Active Label '; ''};
            app.Default_Group = uibuttongroup(app.figure1);
            app.Default_Group.SelectionChangedFcn = createCallbackFcn(app, @Default_GroupSelectionChanged, true);
            app.Default_Group.Title = 'Defaults';
            app.EmptyButton_2 = uiradiobutton(app.Default_Group);
            app.EmptyButton_2.Text = 'Empty';
            app.UnderButton = uiradiobutton(app.Default_Group);
            app.UnderButton.Text = 'Under';
            app.UnderButton.Value = true;
            app.OKButton = uiradiobutton(app.Default_Group);
            app.OKButton.Text = 'OK';
            app.OverButton = uiradiobutton(app.Default_Group);
            app.OverButton.Text = 'Over';
            app.ClassTypesGroup = uibuttongroup(app.figure1);
            app.ClassTypesGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassTypesGroupSelectionChanged, true);
            app.ClassTypesGroup.Title = 'Class Types';
            app.EmptyButton = uiradiobutton(app.ClassTypesGroup);
            app.EmptyButton.Text = 'Empty';
            app.UnderButton_4 = uiradiobutton(app.ClassTypesGroup);
            app.UnderButton_4.Text = 'Under';
            app.UnderButton_4.Value = true;
            app.OKButton_2 = uiradiobutton(app.ClassTypesGroup);
            app.OKButton_2.Text = 'OK';
            app.OverButton_2 = uiradiobutton(app.ClassTypesGroup);
            app.OverButton_2.Text = 'Over';
            app.ClearAll = uibutton(app.figure1, 'push');
            app.ClearAll.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAll.Tag = 'Start';
            app.ClearAll.Enable = 'off';
            app.ClearAll.Text = 'Reset Labels';
            app.Save_to_file = uibutton(app.figure1, 'push');
            app.Save_to_file.ButtonPushedFcn = createCallbackFcn(app, @Save_to_fileButtonPushed, true);
            app.Save_to_file.Tag = 'Start';
            app.Save_to_file.Enable = 'off';
            app.Save_to_file.Text = 'Save to file';
            app.ModeSwitch = uiswitch(app.figure1, 'slider');
            app.ModeSwitch.Items = {'Labeling', 'Testing'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Labeling';
            app.ShiftEditField = uieditfield(app.figure1, 'text');
            app.ShiftEditField.Value = '0';
            app.Shift = uibutton(app.figure1, 'push');
            app.Shift.ButtonPushedFcn = createCallbackFcn(app, @ShiftButtonPushed, true);
            app.Shift.Tag = 'Reset';
            app.Shift.Text = 'Shift';
            app.SetNumField = uieditfield(app.figure1, 'text');
            app.SetNumField.Value = '0';
            app.SetNum = uibutton(app.figure1, 'push');
            app.SetNum.ButtonPushedFcn = createCallbackFcn(app, @SetNumButtonPushed, true);
            app.SetNum.Tag = 'Reset';
            app.SetNum.Text = 'SetNum';
            app.GeneratorButton = uibutton(app.figure1, 'push');
            app.GeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratorButtonPushed, true);
            app.GeneratorButton.Tag = 'Reset';
            app.GeneratorButton.Enable = 'off';
            app.GeneratorButton.Visible = 'off';
            app.GeneratorButton.Text = 'Generate grid Images';
            app.ImagegenerationLabel = uilabel(app.figure1);
            app.ImagegenerationLabel.Text = 'Image generation ';
            app.Modeswitch_img_generation = uiswitch(app.figure1, 'slider');
            app.Modeswitch_img_generation.Items = {'ON', 'OFF'};
            app.Modeswitch_img_generation.ValueChangedFcn = createCallbackFcn(app, @Modeswitch_img_generationValueChanged, true);
            app.Modeswitch_img_generation.Value = 'ON';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageClassify_App_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)imageClassify_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end